classdef lesionView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        newCheckBox              matlab.ui.control.CheckBox
        TextArea                 matlab.ui.control.TextArea
        RButton                  matlab.ui.control.Button
        ZButton                  matlab.ui.control.Button
        YButton                  matlab.ui.control.Button
        XButton                  matlab.ui.control.Button
        LesionReviewButtonGroup  matlab.ui.container.ButtonGroup
        DeleteButton             matlab.ui.control.ToggleButton
        DraftButton              matlab.ui.control.ToggleButton
        KeepButton               matlab.ui.control.ToggleButton
        LesionVisibilitySwitch   matlab.ui.control.Switch
        LesionIndexSpinner       matlab.ui.control.Spinner
        Panel_3                  matlab.ui.container.Panel
        ProgressTextArea         matlab.ui.control.TextArea
        savegifButton            matlab.ui.control.Button
        LoadT2FlairTP2Button     matlab.ui.control.Button
        LoadT2FlairTP1Button     matlab.ui.control.Button
        SAVEniiButton            matlab.ui.control.Button
        BIDSButton               matlab.ui.control.Button
        LoadBrainMaskButton      matlab.ui.control.Button
        Loadlesion2Button        matlab.ui.control.Button
        Loadlesion1Button        matlab.ui.control.Button
        OverlayAlpha             matlab.ui.control.Slider
        ButtonGroup              matlab.ui.container.ButtonGroup
        ExportButton             matlab.ui.control.Button
        x0                       matlab.ui.control.Spinner
        z0                       matlab.ui.control.Spinner
        y0                       matlab.ui.control.Spinner
        PlayButton               matlab.ui.control.ToggleButton
        Button_2                 matlab.ui.control.ToggleButton
        Button                   matlab.ui.control.ToggleButton
        UIAxes                   matlab.ui.control.UIAxes
        LesionVisualizationAxes  matlab.ui.control.UIAxes
    end


properties (Access = private)

    % General properties
    subject = 'sub - xxx'; % e.g. sub-001 - Identifier for the subject
    bidsDir 
    voxelSize = 0.7; % Voxel size for the images, adjust based on your data
    T2FlairInfo % metadata - Information about the T2 flair images (used for saving or processing)

    % Image data properties
    T2FlairTP1 % 3D matrix - T2 flair image at time point 1
    T2FlairTP2 % 3D matrix - T2 flair image at time point 2
    Lesion1 % 3D matrix - Lesion image at time point 1
    Lesion2 % 3D matrix - Lesion image at time point 2
    brainMask % 3D matrix - Brain mask image for overlay or masking purposes
    Les1Img % 3D matrix - Processed lesion image at time point 1, rotated for plotting
    Les2Img % 3D matrix - Processed lesion image at time point 2, rotated for plotting

    % Image analyses properties
    L1 % 3D matrix - Processed lesion image with lables at time point 1 for analysis
    L2 % 3D matrix - Processed lesion image with lables at time point 2 for analysis
    whichT2 % 3D matrix - The variable to hold the currently selected 3D T2 image data
    whichLes % 3D matrix - The variable to hold the currently selected lesion data
    T2Loaded = [false, false] % Tracks whether each T2 image has been loaded
    sizeChange % numLes by 3 - [size1, size2, size2-size1] - To store the results of lesion size change analysis
    lesionCenter % numLes by 3 - [x, y, z] - Center coordinates of lesions, used for visualization adjustments

    % Indexing
    currentIndex % scaler - Index of the currently selected lesion for processing
    currentImageIndex = 1 % 1 or 2 - Indicates the current image being displayed (1 for T2FlairTP1, 2 for T2FlairTP2)
    lesionIndex % numLes by 1 - Array of unique indices for each lesion
    newLesionIndex % numNewLes by 1 - of new lesions in time point 2 that didn't exist in time point 1
    newLesionIndexExport %same with newLesionIndex but must pass the review process as 'keep'

    % Visualization and UI elements
    lesionPatches % numLes*2 by 1 cells - each cell is 1 by 3 cells - patch, timepoint, index - Stores patch objects for visualizing lesions
    brainMaskPatch % Stores patch objects for visualizing the brain mask
    sizeZoom double = 26; % Zoom window size for detailed lesion viewing
    ImageSwitchTimer % Timer for auto-switching between images
    rotationTimer % Timer for rotating the 3D visualization
    hLes % Placeholder for lesion visualization handles
    fig % Main figure for export

    % Lesion processing and review properties
    Lesion1Clean % 3D matrix - Cleaned lesion image at time point 1
    Lesion1Draft % 3D matrix - Draft lesion image at time point 1
    Lesion2Clean % 3D matrix - Cleaned lesion image at time point 2
    Lesion2Draft % 3D matrix - Draft lesion image at time point 2
    LesionReviewStates % numLes by 2 time points - Stores the review state ('keep', 'draft', 'delete') for each lesion

end


methods (Access = private)

    function updateImage(app)

        % this function prepares the 2D view

        % the figure has 6 sections ( 2 rows by 3 cols)
        % bottom 1 by 3 are sagittal coronal and axial views
        % top 1 by 3 are the zoomed in version of the bottom row

        % This way we make one big figure instead of handling 6
        % different axes, which I thought would be more painful

        % Extract slices for the bottom row images
        img21 = mat2gray(squeeze(app.whichT2(app.x0.Value,:,:)))'; % sagittal
        img22 = mat2gray(squeeze(app.whichT2(:,app.y0.Value,:)))'; % coronal
        img23 = mat2gray(squeeze(app.whichT2(:,:,app.z0.Value))); % axial

        % Combine them horizontally
        botrow = [img21 img22 img23];

        % Calculate padding because the images are wider than a square
        padding = (size(botrow,2) - size(botrow,1)*3) / 2;

        % Extract and resize the zoomed-in images for the top row
        % sagittal view is bounded by y/z
        % coronal view is bounded by x/z
        % axial view is bounded by x/y

        img11 = imresize(img21(max(1, app.z0.Value - app.sizeZoom):min(end, app.z0.Value + app.sizeZoom), max(1, app.y0.Value - app.sizeZoom):min(end, app.y0.Value + app.sizeZoom)), [size(botrow,1) size(botrow,1)]);
        img12 = imresize(img22(max(1, app.z0.Value - app.sizeZoom):min(end, app.z0.Value + app.sizeZoom), max(1, app.x0.Value - app.sizeZoom):min(end, app.x0.Value + app.sizeZoom)), [size(botrow,1) size(botrow,1)]);
        img13 = imresize(img23(max(1, app.x0.Value - app.sizeZoom):min(end, app.x0.Value + app.sizeZoom), max(1, app.y0.Value - app.sizeZoom):min(end, app.y0.Value + app.sizeZoom)), [size(botrow,1) size(botrow,1)]);

        % Combine the top row images with padding
        toprow = [zeros(size(botrow,1), floor(padding)-20), img11, zeros(size(botrow,1), 20), img12, zeros(size(botrow,1), 20), img13, zeros(size(botrow,1), ceil(padding)-20)];

        % Combine top and bottom rows
        img = [toprow; botrow];

        % Display the combined image in UIAxes
        imshow(img, 'Parent', app.UIAxes);
        app.UIAxes.Visible = 'off'; % Optionally hide the axes
        hold(app.UIAxes, 'on'); % Keep the original image, allowing overlays to be added

        % Prepare lesion overlays
        les21 = mat2gray(squeeze(app.whichLes(app.x0.Value,:,:)))';
        les22 = mat2gray(squeeze(app.whichLes(:,app.y0.Value,:)))';
        les23 = mat2gray(squeeze(app.whichLes(:,:,app.z0.Value)));

        % Combine lesion overlays horizontally for the bottom row
        botles = [les21 les22 les23];

        % Resize and prepare lesion overlays for the top row
        les11 = imresize(les21(app.z0.Value - app.sizeZoom:app.z0.Value + app.sizeZoom, app.y0.Value - app.sizeZoom:app.y0.Value + app.sizeZoom), [size(botrow,1) size(botrow,1)]);
        les12 = imresize(les22(app.z0.Value - app.sizeZoom:app.z0.Value + app.sizeZoom, app.x0.Value - app.sizeZoom:app.x0.Value + app.sizeZoom), [size(botrow,1) size(botrow,1)]);
        les13 = imresize(les23(app.x0.Value - app.sizeZoom:app.x0.Value + app.sizeZoom, app.y0.Value - app.sizeZoom:app.y0.Value + app.sizeZoom), [size(botrow,1) size(botrow,1)]);
        toples = [zeros(size(botrow,1), padding-20) les11 zeros(size(botrow,1), 20) les12 zeros(size(botrow,1), 20) les13 zeros(size(botrow,1), padding-20)];

        % Combine top and bottom lesion overlays
        img1 = [toples; botles];

        % Create an overlay for the lesion data
        nanMask = img1 ~= 0; % Create a mask to apply the overlay only where lesions are present
        tmpImg = ind2rgb(im2uint8(mat2gray(img1)), hot);
        tmpImg(tmpImg>0&tmpImg<1) = 0;
        app.hLes = imshow(tmpImg, 'Parent', app.UIAxes);
       % set(app.hLes, 'AlphaData', nanMask * app.OverlayAlpha.Value); % Apply the mask with some transparency
        set(app.hLes, 'AlphaData',double(img1) * app.OverlayAlpha.Value);
        startXImg22 = size(img21, 2) + 1;
        startXImg23 = startXImg22 + size(img22, 2);
        startY = size(img21, 1) + 1;

        % Define the zoom area for each image based on the spinner values
        zoomAreaSize = 2 * app.sizeZoom; % The size of the zoomed area

        % Drawing rectangle for img21 (no adjustment needed for startX)
        rectangle('Position', [app.y0.Value - app.sizeZoom, startY + app.z0.Value - app.sizeZoom, zoomAreaSize, zoomAreaSize], 'EdgeColor', 'w', 'LineWidth', 1, 'Parent', app.UIAxes);

        % Drawing rectangle for img22 (adjust startX for img22)
        rectangle('Position', [startXImg22 + app.x0.Value - app.sizeZoom, startY + app.z0.Value - app.sizeZoom, zoomAreaSize, zoomAreaSize], 'EdgeColor', 'w', 'LineWidth', 1, 'Parent', app.UIAxes);

        % Drawing rectangle for img23 (adjust startX for img23)
        rectangle('Position', [startXImg23 + app.y0.Value - app.sizeZoom, startY + app.x0.Value - app.sizeZoom, zoomAreaSize, zoomAreaSize], 'EdgeColor', 'w', 'LineWidth', 1, 'Parent', app.UIAxes);

        timePointText = sprintf('Time %d', app.currentImageIndex); % Convert index to text

        text(app.UIAxes, 0.95, 0.15, timePointText, 'Units', 'normalized', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom', 'Color', 'white', 'FontSize', 15, 'FontWeight', 'bold');
        text(app.UIAxes, 0.82, 0.15, 'L', 'Units', 'normalized', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom', 'Color', [0.5 0.5 0.5], 'FontSize', 15, 'FontWeight', 'bold','FontAngle','italic');
        text(app.UIAxes, 0.62, 0.28, 'L', 'Units', 'normalized', 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom', 'Color', [0.5 0.5 0.5], 'FontSize', 15, 'FontWeight', 'bold','FontAngle','italic');

        hold(app.UIAxes, 'off'); % Release the hold to prevent further overlays on top

    end


    function toggleT2Image(app)

        % Toggles between T2 Flair images from baseline and followup
        % Updates the currently displayed T2 Flair and lesion images

        if app.currentImageIndex == 1 % if current time is baseline
            app.currentImageIndex = 2; % we switch to followup
            if isempty(app.T2FlairTP2) % if followup T2 flair is not loaded
                app.whichT2 = rand(260, 311, 260); % we assign some random number
            else % if followup T2 flair is loaded
                app.whichT2 = app.T2FlairTP2; % switch to followup T2 flair
            end

            if isempty(app.Les2Img) % if followup lesion mask is not loaded
                app.whichLes = rand(260, 311, 260); % assign some random number
            else % if followup lesion mask is not loaded
                app.whichLes = app.Les2Img; % Set to followup lesion mask
            end

        else % if current time is followup
            app.currentImageIndex = 1; % switch to baseline
            if isempty(app.T2FlairTP1) % if baseline T2 flair is not loaded
                app.whichT2 = rand(260, 311, 260);  % assign some random number
            else  % if baseline T2 flair is loaded
                app.whichT2 = app.T2FlairTP1; % Set to baseline T2 flair
            end

            if isempty(app.Les1Img) % if baseline lesion mask is not loaded
                app.whichLes = rand(260, 311, 260);  % assign some random number
            else % if baseline lesion mask is loaded
                app.whichLes = app.Les1Img; % Set to baseline lesion mask
            end

        end

        updateImage(app); % Refresh the T2 flair image display

        updateLesionVisibility(app, app.currentIndex, app.currentImageIndex);

    end


    function analyzeLesions(app)
        % This function analyzes lesions across two time points to identify new,
        % continuing, and merged lesions. It labels each lesion, matches lesions
        % across time points, calculates their volumes, and updates the UI
        % components accordingly.

        % Label the lesions in each time point image using a binary threshold of 0.8
        % and a connectivity of 26.
        [app.L1, ~] = bwlabeln(app.Lesion1 > 0.8, 26);
        [app.L2, ~] = bwlabeln(app.Lesion2 > 0.8, 26);

        % Initialize arrays to store new indices for matched lesions across time points
        lesion1index = nonzeros(unique(app.L1));
        lesion2index = nonzeros(unique(app.L2));
        lesion1indexNew = zeros(size(lesion1index));
        lesion2indexNew = zeros(size(lesion2index));

        updateProgress(app,['Matching lesions across two time points ...']);

        % Loop through each lesion index in time point 1 and match with lesions in time point 2
        % this is stupid
        for i1 = 1:numel(lesion1index)
            updateProgress(app,[num2str(round(i1 / numel(lesion1index) * 100)), ' % done ...']);
            for i2 = 1:numel(lesion2index)
                % Check if lesions overlap between time points
                match = (app.L1 == i1) & (app.L2 == i2);
                if sum(match(:)) > 0
                    % Assign matching lesions the same new index
                    if lesion2indexNew(i2) == 0
                        lesion2indexNew(i2) = i1;
                    else
                        % Handle merged lesions by assigning them the index of the merging lesion
                        lesion1indexNew(i1) = lesion2indexNew(i2);
                    end
                end
            end
        end

        lesion1indexNew(lesion1indexNew==0) = lesion1index(lesion1indexNew==0);
        lesion2indexNew(lesion2indexNew==0) = max(lesion1index)+1:max(lesion1index)+sum(lesion2indexNew==0);

        valueToIndex = containers.Map(unique([lesion1indexNew;lesion2indexNew]), 1:numel(unique([lesion1indexNew;lesion2indexNew])));
        lesion1indexNew = arrayfun(@(x) valueToIndex(x),lesion1indexNew);
        lesion2indexNew = arrayfun(@(x) valueToIndex(x),lesion2indexNew);

        [~, loc] = ismember(app.L1, lesion1index);
        loc(loc > 0) = lesion1indexNew(loc(loc > 0));
        app.L1(loc > 0) = loc(loc > 0);
        [~, loc] = ismember(app.L2, lesion2index);
        loc(loc > 0) = lesion2indexNew(loc(loc > 0));
        app.L2(loc > 0) = loc(loc > 0);

        updateProgress(app,'Sorting lesions by size ...')
        newlist = unique([lesion1indexNew; lesion2indexNew]);
        lesionVol = zeros(nnz(newlist), 1);
        for ii = 1:nnz(newlist)
            lesionVol(ii) = max([nnz(app.L1 == ii) nnz(app.L2 == ii)]); % Number of voxels in the ith lesion
        end

        [~,newlistOrder] = sort(lesionVol,'descend');

        [~, loc] = ismember(app.L1, newlistOrder);
        loc(loc > 0) = newlist(loc(loc > 0));
        app.L1(loc > 0) = loc(loc > 0);

        [~, loc] = ismember(app.L2, newlistOrder);
        loc(loc > 0) = newlist(loc(loc > 0));
        app.L2(loc > 0) = loc(loc > 0);

        % remove any lesion size less than xxx
        app.L1(ismember(app.L1, find(sort(lesionVol,'descend')<10))) = 0;
        app.L2(ismember(app.L2, find(sort(lesionVol,'descend')<10))) = 0;

        updateProgress(app,'Calculating lesion centers ...')

        % Calculate the center of each lesion for visualization
        app.lesionIndex = nonzeros(unique([app.L1; app.L2])); % Combined list of unique lesion indices
        app.lesionCenter = zeros(numel(app.lesionIndex), 3);
        % Note: Image orientation adjustments might be necessary for correct visualization
        tmpL1 = flip(app.L1, 2);
        tmpL1 = flip(tmpL1, 3);
        tmpL2 = flip(app.L2, 2);
        tmpL2 = flip(tmpL2, 3);

        % Initialize the matrix to store size changes across two time points
        app.sizeChange = zeros(numel(app.lesionIndex), 3);

        for ii = 1:numel(app.lesionIndex)
            % Generate binary matrices for each lesion by comparing with their indices
            binaryMat = tmpL1 == app.lesionIndex(ii);
            % Count the number of true values (lesion volume) in time point 1
            app.sizeChange(ii, 1) = sum(binaryMat(:));
            % Repeat for time point 2
            tmp2 = tmpL2 == app.lesionIndex(ii);
            app.sizeChange(ii, 2) = sum(tmp2(:));
            % If a lesion is not present in time point 1, use its presence in time point 2
            if sum(binaryMat(:)) < 1
                binaryMat = tmp2;
            end
            % Calculate the centroid of the lesion by finding the index of maximum sum along each dimension
            [~, app.lesionCenter(app.lesionIndex(ii), 1)] = max(squeeze(sum(sum(binaryMat, 2), 3)));
            [~, app.lesionCenter(app.lesionIndex(ii), 2)] = max(squeeze(sum(sum(binaryMat, 1), 3)));
            [~, app.lesionCenter(app.lesionIndex(ii), 3)] = max(squeeze(sum(sum(binaryMat, 1), 2)));
        end
        % Calculate the change in lesion size between two time points and convert to volume
        app.sizeChange(:, 3) = (app.sizeChange(:, 2) - app.sizeChange(:, 1)) * app.voxelSize;

        % Set the limits and enable the lesion index spinner based on available lesions
        app.LesionIndexSpinner.Limits = [1, size(app.lesionCenter, 1)];
        app.LesionIndexSpinner.Enable = 'on';

        % Initialize the review states for each lesion across both time points as "keep"
        app.LesionReviewStates = repmat("keep", numel(app.lesionIndex), 2);

        % Prepare clean and draft lesion matrices for both time points
        app.Lesion1Clean = app.L1;
        app.Lesion1Draft = zeros(size(app.L1));
        app.Lesion2Clean = app.L2;
        app.Lesion2Draft = zeros(size(app.L2));

        % Display summary information about lesions in TextArea
        infoText = sprintf('Time 1 - %d lesions (%d ml)\nTime 2 - %d lesions (%d ml)', ...
            nnz(unique(app.L1)), round(nnz(app.L1(:)) * app.voxelSize), ...
            nnz(unique(app.L2)), round(nnz(app.L2(:)) * app.voxelSize));
        app.TextArea.Value = infoText;

        % Make the save GIF and export button visible and enabled
        app.savegifButton.Visible = true;
        app.savegifButton.Enable = 'on';
        app.ExportButton.Visible = true;
        app.ExportButton.Enable = 'on';

        % Identify new lesions in time point 2 that were not present in time point 1
        lesionList2 = unique(app.L2);
        app.newLesionIndex = lesionList2(~ismember(unique(app.L2), unique(app.L1)));

        if isempty(app.newLesionIndex)
            app.newCheckBox.Enable = 'off';
            app.newCheckBox.Visible = false;
        else
            app.newCheckBox.Enable = 'on';
            app.newCheckBox.Visible = true;
        end

    end

    function drawBrainMask(app)
            if ~isempty(app.brainMask)
                [x, y, z] = meshgrid(1:size(app.brainMask,2), 1:size(app.brainMask,1), 1:size(app.brainMask,3));
                [f, v] = isosurface(x, y, z, app.brainMask, 0.5);
                app.brainMaskPatch = patch(app.LesionVisualizationAxes, 'Faces', f, 'Vertices', v, 'FaceColor', [0.5 0.5 0.5], 'EdgeColor', 'none', 'FaceAlpha', 0.05);
                view(app.LesionVisualizationAxes, 3);
                axis(app.LesionVisualizationAxes, 'equal');
                camlight(app.LesionVisualizationAxes, 'headlight');
                lighting(app.LesionVisualizationAxes, 'gouraud');
            end
        end

        function drawLesions(app)
            [x, y, z] = meshgrid(1:size(app.L1,2), 1:size(app.L1,1), 1:size(app.L1,3));
            app.lesionPatches = {};

            for idx = 1:numel(app.lesionIndex)
                updateProgress(app,['Drawing lesion # ' num2str(idx) ' ...'])
                drawLesion(app, app.lesionIndex(idx), x, y, z);
            end
        end

        function drawLesion(app, idx, x, y, z)
            % Draw lesion for L1

            if any(app.L1(:) ==  app.lesionIndex(idx))
                drawMat = app.L1 ==  app.lesionIndex(idx);
                if nnz(drawMat) > 0 % Check if non-empty
                    [f, v] = isosurface(x, y, z, drawMat, 0.8);
                    lesionPatch = patch(app.LesionVisualizationAxes, 'Faces', f, 'Vertices', v, 'FaceColor', 'green', 'FaceAlpha', 0, 'EdgeColor', 'none'); % Initially invisible
                    app.lesionPatches{end+1} = {lesionPatch, 1, idx}; % Time point 1
                end
            end

            % Repeat for L2 with appropriate checks and adjustments
            if any(app.L2(:) ==  app.lesionIndex(idx))
                drawMat = app.L2 ==  app.lesionIndex(idx);
                if nnz(drawMat) > 0
                    [f, v] = isosurface(x, y, z, drawMat, 0.8);
                    lesionPatch = patch(app.LesionVisualizationAxes, 'Faces', f, 'Vertices', v, 'FaceColor', 'green', 'FaceAlpha', 0, 'EdgeColor', 'none'); % Initially invisible
                    app.lesionPatches{end+1} = {lesionPatch, 2, idx}; % Time point 2
                end
            end
        end

        function updateLesionVisibility(app, selectedLesionIndex, timePoint)

            switchState = app.LesionVisibilitySwitch.Value; % 'One' or 'All' switch states

            for i = 1:length(app.lesionPatches)
                patchInfo = app.lesionPatches{i};
                lesionPatch = patchInfo{1}; % The patch object
                lesionTimePoint = patchInfo{2}; % Time point of the lesion

                % Ensure visibility and coloring apply only to the current time point
                if lesionTimePoint == timePoint
                    if strcmp(switchState, 'All')
                        % For "All" state, color all lesions based on their review state
                        currentState = app.LesionReviewStates(patchInfo{3}, timePoint);
                        lesionPatch.FaceAlpha = 1; % Make lesion visible
                        updatePatchColor(app, lesionPatch, currentState); % Update color based on state
                    else
                        % For "One" state, only the selected lesion is fully visible
                        lesionPatch.FaceAlpha = double(patchInfo{3} == selectedLesionIndex);
                        if patchInfo{3} == selectedLesionIndex
                            % Use review state to color the selected lesion
                            currentState = app.LesionReviewStates(patchInfo{3}, timePoint);
                            updatePatchColor(app, lesionPatch, currentState);
                        else
                            lesionPatch.FaceColor = [0.5, 0.5, 0.5]; % Default color for non-selected lesions
                        end
                    end
                else
                    lesionPatch.FaceAlpha = 0; % Make lesions from other time points invisible
                end
            end

        end

        function updatePatchColor(~,lesionPatch, currentState)
            % Define patch color based on the lesion's review state
            switch currentState
                case 'keep'
                    lesionPatch.FaceColor = [0, 1, 0]; % Green
                case 'draft'
                    lesionPatch.FaceColor = [1, 1, 0]; % Yellow
                case 'delete'
                    lesionPatch.FaceColor = [1, 0, 0]; % Red
                otherwise
                    lesionPatch.FaceColor = [0.5, 0.5, 0.5]; % Default/undefined state
            end
        end
        function updateLesionMasks(app)
            currentState = app.LesionReviewStates(app.currentIndex,app.currentImageIndex); % Get current review state
            switch currentState
                case 'keep'
                    if app.currentImageIndex == 1
                        updateProgress(app,'saving to time point 1 clean')
                        app.Lesion1Clean(app.L1==app.currentIndex) = app.currentIndex;
                        app.Lesion1Draft(app.L1==app.currentIndex) = 0;
                    else
                        updateProgress(app,'saving to time point 2 clean')
                        app.Lesion2Clean(app.L2==app.currentIndex) = app.currentIndex;
                        app.Lesion2Draft(app.L2==app.currentIndex) = 0;
                    end

                case 'draft'
                    if app.currentImageIndex == 1
                        updateProgress(app,'saving to time point 1 draft')
                        app.Lesion1Clean(app.L1==app.currentIndex) = 0;
                        app.Lesion1Draft(app.L1==app.currentIndex) = app.currentIndex;
                    else
                        updateProgress(app,'saving to time point 2 draft')
                        app.Lesion2Clean(app.L2==app.currentIndex) = 0;
                        app.Lesion2Draft(app.L2==app.currentIndex) = app.currentIndex;
                    end
                case 'delete'
                    if app.currentImageIndex == 1
                        updateProgress(app,'removing from time point 1 clean & draft')
                        app.Lesion1Clean(app.L1==app.currentIndex) = 0;
                        app.Lesion1Draft(app.L1==app.currentIndex) = 0;
                    else
                        updateProgress(app,'removing from time point 2 clean & draft')
                        app.Lesion2Clean(app.L2==app.currentIndex) = 0;
                        app.Lesion2Draft(app.L2==app.currentIndex) = 0;
                    end
            end
        end

        function updateReviewStateUI(app)

            if app.newCheckBox.Value
                currentState = app.LesionReviewStates(min([app.currentIndex max(app.newLesionIndex)]),app.currentImageIndex); % Get current review state
            else
                currentState = app.LesionReviewStates(min([app.currentIndex max(app.lesionIndex)]),app.currentImageIndex); % Get current review state
            end

            % Find and select the corresponding button in the ButtonGroup
            switch currentState
                case 'keep'
                    app.LesionReviewButtonGroup.SelectedObject = app.KeepButton;
                    app.KeepButton.BackgroundColor = [0 1 0];
                    app.DraftButton.BackgroundColor = [1 1 1];
                    app.DeleteButton.BackgroundColor = [1 1 1];
                case 'draft'
                    app.LesionReviewButtonGroup.SelectedObject = app.DraftButton;
                    app.KeepButton.BackgroundColor = [1 1 1];
                    app.DraftButton.BackgroundColor = [1 1 0];
                    app.DeleteButton.BackgroundColor = [1 1 1];
                case 'delete'
                    app.LesionReviewButtonGroup.SelectedObject = app.DeleteButton;
                    app.KeepButton.BackgroundColor = [1 1 1];
                    app.DraftButton.BackgroundColor = [1 1 1];
                    app.DeleteButton.BackgroundColor = [1 0 0];
                otherwise
                    % If no state is set, default to 'keep'
                    app.LesionReviewButtonGroup.SelectedObject = app.KeepButton;

            end
            updateLesionVisibility(app, app.currentIndex, app.currentImageIndex);
        end

        function rotateView(app)

            viewAngle = get(app.LesionVisualizationAxes, 'View');  % Get the current view angle of the axes
            viewAngle(1) = viewAngle(1) + 5;      % Increment the azimuth angle for rotation (adjust the value as needed)
            view(app.LesionVisualizationAxes, viewAngle);          % Update the view angle to rotate the plot

        end

        function [indexedImg, map] = captureUIAxesFrame(app)
            % Capture the current frame from app.UIAxes
            frame = getframe(app.UIAxes);
            % Convert the frame to an image suitable for GIF creation
            [image, map] = frame2im(frame);
            if isempty(map)
                % For RGB images
                [indexedImg, map] = rgb2ind(image, 256, 'nodither');
            else
                % For indexed images
                indexedImg = rgb2ind(image, map, 'nodither');
            end
        end

        function saveUIAxesAsGif(app)

            folder = uigetdir; % Let user select save directory
            if folder == 0
                return; % User cancelled
            end

            switch app.LesionVisibilitySwitch.Value
                case 'One'
                    gifFilename1 = sprintf('%s/%s_lesionSurf_%s.gif',folder,app.subject,num2str(app.currentIndex));

                case 'All'
                    gifFilename1 = sprintf('%s/%s_lesionSurf_%s.gif',folder,app.subject,'All');
            end

            gifFilename = sprintf('%s/%s_lesionVol_%s_%s_%s_%s.gif',folder,app.subject,num2str(app.currentIndex),num2str(app.x0.Value), num2str(size(app.L1,2)-app.y0.Value),num2str(size(app.L1,3)-app.z0.Value));

            % Loop through each frame
            for k = 1:2
                % Temporarily export each frame to a PNG file
                tempFilename = sprintf('tempFrame_%d.png', k);
                tempFilename1 = sprintf('tempFrame1_%d.png', k);
                app.currentImageIndex = 3-k;
                toggleT2Image(app);
                % Export the current frame as a high-resolution image
                exportgraphics(app.UIAxes, tempFilename, 'Resolution', 300);
                exportgraphics(app.LesionVisualizationAxes, tempFilename1, 'Resolution', 300);

                % Read the exported image back into MATLAB
                [img, ~] = imread(tempFilename);
                [img1, ~] = imread(tempFilename1);
                [indexedImg, map] = rgb2ind(img, 256); % Convert image to indexed format
                [indexedImg1, map1] = rgb2ind(img1, 256);
                % Write the indexed image to the GIF
                if k == 1
                    imwrite(indexedImg, map, gifFilename, 'gif', 'LoopCount', Inf, 'DelayTime', 1);
                    imwrite(indexedImg1, map1, gifFilename1, 'gif', 'LoopCount', Inf, 'DelayTime', 1);

                else
                    imwrite(indexedImg, map, gifFilename, 'gif', 'WriteMode', 'append', 'DelayTime', 1);
                    imwrite(indexedImg1, map1, gifFilename1, 'gif', 'WriteMode', 'append', 'DelayTime', 1);

                end

                % Delete the temporary PNG file
                delete(tempFilename);
                delete(tempFilename1);

            end

        end


        function drawReport(app,whereReport)

            app.newCheckBox.Value = 0;
            app.LesionIndexSpinner.Limits = [1, max(app.lesionIndex)]; 
            app.LesionIndexSpinner.Enable = 'on';
            app.LesionIndexSpinner.Value = app.currentIndex;
            updateLesionVisibility(app, app.currentIndex, app.currentImageIndex);
            app.x0.Value = app.lesionCenter(app.currentIndex,1);
            app.y0.Value = app.lesionCenter(app.currentIndex,2);
            app.z0.Value = app.lesionCenter(app.currentIndex,3);

            for ii = 1:2 % overlay yes and no
                app.OverlayAlpha.Value = ii-1;
               

                for k = 1:2 % Assuming you want to loop through two images
                    updateProgress(app,['Saving to report: 2D view lesion ' num2str(app.currentIndex) ' timepoint ' num2str(k) ' - ' num2str(ii) '...'])
                    app.currentImageIndex = 3 - k;

                    toggleT2Image(app); %
                    updateImage(app); % sometimes it's too slow

                    % Temporarily export each frame to a PNG file for high-resolution capture
                    tempFilename1 = sprintf('tempFrame1_%d.png', k);
                    exportgraphics(app.UIAxes, tempFilename1, 'Resolution', 300);

                    % Read the captured image
                    [img, ~] = imread(tempFilename1);


                    imageWidth = 0.45;  % Normalized width for each image
                    imageHeight = size(img,1)/size(img,2) * imageWidth;  % Normalized height for each image
                    gapWidth = (1 - 2 * imageWidth) / 3;
                    gapHeight = (1 - 4 * imageHeight) / 3;

           
                    % Calculate target position for the image
                    if whereReport == 1 % top half
                        if k == 1
                            % First image positioning

                            targetPosition = [gapWidth   1-gapHeight-imageHeight-(ii-1)*imageHeight/1.4-0.1   imageWidth   imageHeight]; 

                        else
                            % Second image positioning
                            targetPosition = [gapWidth * 2 + imageWidth   1-gapHeight-imageHeight-(ii-1)*imageHeight/1.4-0.1   imageWidth   imageHeight]; 
                        end
                    else % bottom half
                        if k == 1
                            % First image positioning
                            targetPosition = [gapWidth  gapHeight+imageHeight-(ii-1)*imageHeight/1.4-0.05   imageWidth   imageHeight];
                        else
                            % Second image positioning
                            targetPosition = [gapWidth * 2 + imageWidth  gapHeight+imageHeight-(ii-1)*imageHeight/1.4-0.05   imageWidth   imageHeight];
                        end
                    end

                    % Create axes for the image and adjust to target position
                    imgAxes = axes('Parent', app.fig, 'Position', targetPosition);
                    imshow(img,'InitialMagnification', 'fit', 'Parent', imgAxes);


                    if ii == 1 % if no overlay
                        if k == 1 % if baseline
                            if app.currentIndex == 1
                                textStr = ['Lesion ' num2str(app.currentIndex) ' (largest lesion) - baseline'];
                            else
                                textStr = ['Lesion ' num2str(app.currentIndex) ' - baseline'];
                            end
                        else % if followup
                            tmpMat = app.L2==app.lesionIndex(app.currentIndex);
                            if app.currentIndex == 1
                                textStr = ['Lesion ' num2str(app.currentIndex) ' (largest lesion) - followup'];
                            else                               
                                textStr = ['Lesion ' num2str(app.currentIndex) ' - followup - ' num2str(sum(tmpMat(:))*app.voxelSize) ' ml'];
                            end
                        end
                        text(0, 1.1, textStr, ...
                            'FontSize', 15, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top', ...
                            'Units', 'normalized', 'Parent', imgAxes,'FontWeight','bold');
                    end

                    set(imgAxes, 'XTick', [], 'YTick', [], 'Box', 'on');

                    % Clean up temporary file
                    delete(tempFilename1);
                end
            end
        end

        
        function setupFigure(app)

            % set up figure for export 

            dpi = 300;
            a4WidthInches = 8.27; % A4 width in inches
            a4HeightInches = 11.69; % A4 height in inches
            a4WidthPixels = a4WidthInches * dpi; % Convert inches to pixels
            a4HeightPixels = a4HeightInches * dpi; % Convert inches to pixels

            app.fig = figure;
            % Set figure units to pixels for on-screen size control
            set(app.fig, 'Units', 'pixels', ...
                'Position', [100, 100, a4WidthPixels, a4HeightPixels], ...
                'Visible', 'off');

            set(app.fig, 'PaperUnits', 'inches', ...
                'PaperSize', [a4WidthInches, a4HeightInches], ...
                'PaperPositionMode', 'manual','PaperPosition', [0 0 a4WidthInches a4HeightInches]);
        end

        function updateProgress(app, message)
            % Append new message to the TextArea
            currentText = app.ProgressTextArea.Value;
            if iscell(currentText)
                % If there are multiple lines already, append the new message
                app.ProgressTextArea.Value = [currentText; {message}];
            else
                % If it's the first message
                app.ProgressTextArea.Value = {currentText; message};
            end
            % Automatically scroll to the bottom of the TextArea
            drawnow;
            scroll(app.ProgressTextArea, 'bottom');
        end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateProgress(app,'Starting app ...')
            app.x0.Value = 100;
            app.y0.Value = 100;
            app.z0.Value = 100;
            % Initialize with a random matrix of size 260x311x260
            app.whichT2 = rand(260, 311, 260);
            app.whichLes = rand(260, 311, 260);

            % Initial call to display the image
            updateImage(app);
            app.x0.Limits = [1 size(app.whichT2, 3)];
            app.y0.Limits = [1 size(app.whichT2, 2)];
            app.z0.Limits = [1 size(app.whichT2, 1)];

            % Call updateImage to display the initial random matrix or handle as needed
            updateImage(app);
            app.LesionVisibilitySwitch.Value = 'One';
            app.currentImageIndex = 1;
            app.lesionIndex = 1;

            app.LesionIndexSpinner.Limits = [1, 1]; % Minimal safe range
            app.LesionIndexSpinner.Value = 1; % Set an initial value
            app.currentIndex = 1;
            app.LesionIndexSpinner.Enable = 'off'; % Disable until lesions are loaded

            app.savegifButton.Visible = false;
            app.savegifButton.Enable = 'off';
            app.ExportButton.Visible = false;
            app.ExportButton.Enable = 'off';

            app.ImageSwitchTimer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'TimerFcn', @(~,~)toggleT2Image(app));

            app.rotationTimer = timer(...
                'ExecutionMode', 'fixedRate', ...  % Execute the timer repeatedly at a fixed rate
                'Period', 0.15, ...                 % Set the period of the timer (adjust as needed for rotation speed)
                'TimerFcn', @(~,~)rotateView(app)); % Function to rotate the plot


        end

        % Value changed function: x0
        function x0ValueChanged(app, event)
            updateImage(app);
        end

        % Value changed function: y0
        function y0ValueChanged(app, event)
            updateImage(app);
        end

        % Value changed function: z0
        function z0ValueChanged(app, event)
            updateImage(app);
        end

        % Button pushed function: LoadT2FlairTP1Button
        function LoadT2FlairTP1ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select the T2 Flair Time Point 1 File');
            if isequal(file, 0) || isequal(path, 0)
                updateProgress(app,'User selected Cancel');
            else
                fullPath = fullfile(path, file);
                app.T2FlairInfo = niftiinfo(fullPath);
                app.T2FlairTP1 = niftiread(fullPath); % Load the image data
                app.T2FlairTP1 = flip(app.T2FlairTP1,2);app.T2FlairTP1 = flip(app.T2FlairTP1,3);
                app.LoadT2FlairTP1Button.BackgroundColor = [0.5 0.5 0.5]; % Change button color to dark gray to indicate loading
               

                % Automatically update whichT2 for immediate feedback
                app.whichT2 = app.T2FlairTP1;

                % If T2FlairTP2 is not loaded yet, mirror T2FlairTP1
                if isempty(app.T2FlairTP2)
                    app.T2FlairTP2 = app.T2FlairTP1;
                end

                app.whichT2 = app.T2FlairTP1;
                app.currentImageIndex = 1;
                updateImage(app); % Refresh to show the loaded image
            end
        end

        % Button pushed function: LoadT2FlairTP2Button
        function LoadT2FlairTP2ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select the T2 Flair Time Point 2 File');
            if isequal(file, 0) || isequal(path, 0)
                updateProgress(app,'User selected Cancel');
            else
                fullPath = fullfile(path, file);
                app.T2FlairInfo = niftiinfo(fullPath);
                app.T2FlairTP2 = niftiread(fullPath); % Load the image data
                app.T2FlairTP2 = flip(app.T2FlairTP2,2);app.T2FlairTP2 = flip(app.T2FlairTP2,3);

                app.LoadT2FlairTP2Button.BackgroundColor = [0.5 0.5 0.5]; % Change button color to dark gray to indicate loading

                % If T2FlairTP1 is not loaded yet, mirror T2FlairTP2
                if isempty(app.T2FlairTP1)
                    app.T2FlairTP1 = app.T2FlairTP2;
                end

                app.whichT2 = app.T2FlairTP2; 
                app.currentImageIndex = 2; 
                updateImage(app); % Refresh to show the loaded image
            end
        end

        % Button pushed function: Loadlesion1Button
        function Loadlesion1ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select Lesion 1 File');
            if ~isequal(file, 0)
                app.Lesion1 = niftiread(fullfile(path, file));
                app.Les1Img = flip(app.Lesion1,2);app.Les1Img = flip(app.Les1Img,3);

                app.whichLes = app.Les1Img;
                app.currentImageIndex = 1;
                updateImage(app);

                app.Loadlesion1Button.BackgroundColor = [0.5 0.5 0.5];
                % If both lesions are loaded, you can call the analysis
                if ~isempty(app.Lesion1) && ~isempty(app.Lesion2)
                    analyzeLesions(app);
                    drawLesions(app);
                    updateImage(app); % Refresh the T2 flair image display
                    updateLesionVisibility(app,app.currentIndex, app.currentImageIndex);

                end
            end
        end

        % Button pushed function: Loadlesion2Button
        function Loadlesion2ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select Lesion 2 File');
            if ~isequal(file, 0)
                app.Lesion2 = niftiread(fullfile(path, file));
                app.Les2Img = flip(app.Lesion2,2);app.Les2Img = flip(app.Les2Img,3);
                app.whichLes = app.Les2Img;
                app.currentImageIndex = 2;
                updateImage(app);
                app.Loadlesion2Button.BackgroundColor = [0.5 0.5 0.5];
                % If both lesions are loaded, you can call the analysis
                if ~isempty(app.Lesion1) && ~isempty(app.Lesion2)
                    analyzeLesions(app);
                    drawLesions(app);
                    updateImage(app); % Refresh the T2 flair image display
                    updateLesionVisibility(app,app.currentIndex, app.currentImageIndex);

                end
            end
        end

        % Button pushed function: LoadBrainMaskButton
        function LoadBrainMaskButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii;*.nii.gz', 'NIfTI Files (*.nii, *.nii.gz)'}, 'Select Brain Mask File');
            if isequal(file, 0) || isequal(path, 0)
                updateProgress(app,'User selected Cancel');
            else
                brainMaskFullPath = fullfile(path, file);
                app.brainMask = niftiread(brainMaskFullPath);
                app.LoadBrainMaskButton.BackgroundColor = [0.5 0.5 0.5];
                %app.brainMask = flip(app.brainMask,2);app.brainMask = flip(app.brainMask,3);
                drawBrainMask(app);
            end
        end

        % Value changed function: LesionIndexSpinner
        function LesionIndexSpinnerValueChanged(app, event)
            app.LesionVisibilitySwitch.Value = 'One';

            if ~app.newCheckBox.Value
                whichIndex = app.lesionIndex;
            else
                whichIndex = app.newLesionIndex;
            end
            app.LesionIndexSpinner.Limits = [1, numel(whichIndex)];

            app.currentIndex = whichIndex(min([app.LesionIndexSpinner.Value numel(whichIndex)]));
            
            updateLesionVisibility(app, app.currentIndex, app.currentImageIndex);
            app.x0.Value = app.lesionCenter(app.currentIndex,1);
            app.y0.Value = app.lesionCenter(app.currentIndex,2);
            app.z0.Value = app.lesionCenter(app.currentIndex,3);

            updateImage(app);
            updateReviewStateUI(app);

        end

        % Button pushed function: BIDSButton
        function BIDSButtonPushed(app, event)
            % Open folder selection dialog
            subDir = uigetdir();
            if isequal(subDir, 0)
                updateProgress(app,'User selected Cancel');
                return;
            end
            tmpVal = strfind(string(subDir), filesep);
            % Extract the subject ID
            app.subject = char(extractAfter(string(subDir), tmpVal(end)));
            % Extract the BIDS directory
            app.bidsDir = char(extractBefore(string(subDir), tmpVal(end-2)));

            LamDir = 'Laminate';
            LesDir = 'ManualLesion';

            updateProgress(app,['Loading from :'  app.bidsDir])
            % Attempt to load each file
            try
                % T2FlairTP1
                t2FlairTP1File = dir(fullfile(app.bidsDir,'derivatives',LamDir,app.subject, 'ses-01', '*acq-2D*FLAIR.nii.gz'));
                if isempty(t2FlairTP1File)
                   t2FlairTP1File = dir(fullfile(app.bidsDir,'derivatives',LamDir,app.subject, 'ses-01', '*acq-3D*FLAIR.nii.gz'));
                end

                if ~isempty(t2FlairTP1File)
                    updateProgress(app,['Loading T2 Flair time point 1 from : ', fullfile(t2FlairTP1File.folder, t2FlairTP1File.name)]);
                    app.T2FlairTP1 = niftiread(fullfile(t2FlairTP1File.folder, t2FlairTP1File.name));
                    app.T2FlairTP1 = flip(app.T2FlairTP1,2);app.T2FlairTP1 = flip(app.T2FlairTP1,3);

                    app.LoadT2FlairTP1Button.BackgroundColor = [0.5, 0.5, 0.5]; % Change color to indicate loading
                else
                    updateProgress(app,['*** No baseline FLAIR found, please load manually ***'])
                end

                % T2FlairTP2
                t2FlairTP2File = dir(fullfile(app.bidsDir,'derivatives',LamDir,app.subject, 'ses-02', '*acq-2D*FLAIR.nii.gz'));
                if isempty(t2FlairTP2File)
                   t2FlairTP2File = dir(fullfile(app.bidsDir,'derivatives',LamDir,app.subject, 'ses-02', '*acq-3D*FLAIR.nii.gz'));
                end
                if ~isempty(t2FlairTP2File)
                    updateProgress(app,['Loading T2 Flair time point 2 from : ', fullfile(t2FlairTP2File.folder, t2FlairTP2File.name)]);
                    app.T2FlairTP2 = niftiread(fullfile(t2FlairTP2File.folder, t2FlairTP2File.name));
                    app.T2FlairTP2 = flip(app.T2FlairTP2,2);app.T2FlairTP2 = flip(app.T2FlairTP2,3);
                    app.LoadT2FlairTP2Button.BackgroundColor = [0.5, 0.5, 0.5];
                else
                    updateProgress(app,['*** No followup FLAIR found, please load manually ***'])
                end

                app.whichT2 = app.T2FlairTP2;
                app.currentImageIndex = 2;
                updateImage(app);
                clf(app.LesionVisualizationAxes);

                % BrainMask
                brainMaskFile = dir(fullfile(app.bidsDir,'derivatives',LamDir,app.subject, 'ses-01', '*brain_mask.nii.gz'));
                if ~isempty(brainMaskFile)
                    updateProgress(app,['Loading brain mask from : ', fullfile(brainMaskFile(1).folder, brainMaskFile(1).name)]);
                    app.brainMask = niftiread(fullfile(brainMaskFile(1).folder, brainMaskFile(1).name));
                    app.LoadBrainMaskButton.BackgroundColor = [0.5, 0.5, 0.5];
                    updateProgress(app,'Drawing brain mask ...')
                    drawBrainMask(app);
                else
                    updateProgress(app,['*** No BrainMask found, please load manually ***'])
                end

                % Lesion1
                lesion1File = dir(fullfile(app.bidsDir,'derivatives',LesDir,app.subject, 'ses-01', '*acq-2D*lesionManual*.nii.gz'));
                if isempty(lesion1File)
                    lesion1File = dir(fullfile(app.bidsDir,'derivatives',LesDir,app.subject, 'ses-01', '*acq-3D*lesionManual*.nii.gz'));
                end
                if ~isempty(lesion1File)
                    updateProgress(app,['Loading Lesion mask time point 1 from : ', fullfile(lesion1File.folder, lesion1File.name)]);
                    app.Lesion1 = niftiread(fullfile(lesion1File.folder, lesion1File.name));
                    app.Les1Img = flip(app.Lesion1,2);app.Les1Img = flip(app.Les1Img,3);
                    app.T2FlairInfo = niftiinfo(fullfile(lesion1File.folder, lesion1File.name));
                    app.Loadlesion1Button.BackgroundColor = [0.5, 0.5, 0.5];
                else
                    updateProgress(app,['*** No baseline lesion found, please load manually ***'])
                end

                % Lesion2
                lesion2File = dir(fullfile(app.bidsDir,'derivatives',LesDir,app.subject, 'ses-02', '*acq-2D*lesionManual*.nii.gz'));
                if isempty(lesion2File)
                    lesion2File = dir(fullfile(app.bidsDir,'derivatives',LesDir,app.subject, 'ses-02', '*acq-3D*lesionManual*.nii.gz'));
                end              
                if ~isempty(lesion2File)
                    updateProgress(app,['Loading Lesion mask time point 2 from : ', fullfile(lesion2File.folder, lesion2File.name)]);                    
                    app.Lesion2 = niftiread(fullfile(lesion2File.folder, lesion2File.name));
                    app.Les2Img = flip(app.Lesion2,2);app.Les2Img = flip(app.Les2Img,3);
                    app.whichLes = app.Les2Img;
                    app.Loadlesion2Button.BackgroundColor = [0.5, 0.5, 0.5];
                else
                    updateProgress(app,['*** No followup lesion found, please load manually ***'])
                end

                if ~isempty(app.Lesion1) && ~isempty(app.Lesion2)
                    analyzeLesions(app); 
                    updateProgress(app,'Drawing lesions ...')
                    drawLesions(app);
                    updateImage(app); % Refresh the T2 flair image display
                    updateLesionVisibility(app, app.currentIndex, app.currentImageIndex);

                end

            catch ME
                updateProgress(app,['Error loading files: ', ME.message]);
            end
        end

        % Value changed function: LesionVisibilitySwitch
        function LesionVisibilitySwitchValueChanged(app, event)
            updateLesionVisibility(app, app.currentIndex, app.currentImageIndex);

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;

            switch selectedButton
                case '1'
                    stop(app.ImageSwitchTimer); % Stop the timer if running
                    app.currentImageIndex = 1;
                    if isempty(app.T2FlairTP1)
                        app.whichT2 = rand(260, 311, 260);
                    else
                        app.whichT2 = app.T2FlairTP1; % Set to first image
                    end
                    if isempty(app.Les1Img)
                        app.whichLes = rand(260, 311, 260);
                    else
                        app.whichLes = app.Les1Img; % Set to first image
                    end

                    updateImage(app); % Refresh the image display for Time 1
                    updateLesionVisibility(app, app.currentIndex, app.currentImageIndex);
                    
                    if ~isempty(app.Lesion1)
                    updateReviewStateUI(app);
                    end

                case '2'
                    stop(app.ImageSwitchTimer); % Stop the timer if running
                    app.currentImageIndex = 2;
                    if isempty(app.T2FlairTP2)
                        app.whichT2 = rand(260, 311, 260);
                    else
                        app.whichT2 = app.T2FlairTP2; % Set to first image
                    end
                    if isempty(app.Les2Img)
                        app.whichLes = rand(260, 311, 260);
                    else
                        app.whichLes = app.Les2Img; % Set to first image
                    end
                    updateImage(app); % Refresh the image display for Time 2
                    updateLesionVisibility(app, app.currentIndex, app.currentImageIndex);

                    if ~isempty(app.Lesion2)
                        updateReviewStateUI(app);
                    end

                case 'Play'
                    start(app.ImageSwitchTimer);

                    if ~isempty(app.Lesion1) && ~isempty(app.Lesion2)

                        updateReviewStateUI(app);
                    end
            end
        end

        % Value changing function: OverlayAlpha
        function OverlayAlphaValueChanging(app, event)
            alphaValue = app.OverlayAlpha.Value; % Get the current value of the slider
            if isfield(app, 'hLes') && isvalid(app.hLes) % Check if the overlay handle exists and is valid
                set(app.hLes, 'AlphaData', alphaValue); % Adjust the overlay's alpha transparency
            end
            updateImage(app);
        end

        % Value changed function: OverlayAlpha
        function OverlayAlphaValueChanged(app, event)
            alphaValue = app.OverlayAlpha.Value; % Get the current value of the slider
            if isfield(app, 'hLes') && isvalid(app.hLes) % Check if the overlay handle exists and is valid
                set(app.hLes, 'AlphaData', alphaValue); % Adjust the overlay's alpha transparency
            end
            updateImage(app);            
        end

        % Selection changed function: LesionReviewButtonGroup
        function LesionReviewButtonGroupSelectionChanged(app, event)
            selectedButton = app.LesionReviewButtonGroup.SelectedObject.Text;
            % Default all buttons to white background
            % Update the review state for the current lesion
            app.LesionReviewStates(app.currentIndex,app.currentImageIndex) = lower(selectedButton);
            % Update lesion masks based on the selected review state
            updateLesionMasks(app);
            updateReviewStateUI(app);

        end

        % Button pushed function: SAVEniiButton
        function SAVEniiButtonPushed(app, event)
            folder = uigetdir; % Let user select save directory
            if folder == 0
                return; % User cancelled
            end
            folder = fullfile(app.bidsDir,'derivatives','tmp_lesion',app.subject);
            mkdir(folder);

            app.Lesion1Clean = single(cast(app.Lesion1Clean, 'like', app.Lesion1)); % Example conversion
            app.Lesion1Draft = single(cast(app.Lesion1Draft, 'like', app.Lesion1)); % Example conversion
            app.Lesion2Clean = single(cast(app.Lesion2Clean, 'like', app.Lesion1)); % Example conversion
            app.Lesion2Draft = single(cast(app.Lesion2Draft, 'like', app.Lesion1)); % Example conversion

            % Save each mask as a nifti file
            niftiwrite(app.Lesion1Clean, fullfile(folder, 'ses-01_lesion_clean'),app.T2FlairInfo);
            niftiwrite(app.Lesion1Draft, fullfile(folder, 'ses-01_lesion_draft'),app.T2FlairInfo);
            niftiwrite(app.Lesion2Clean, fullfile(folder, 'ses-02_lesion_clean'),app.T2FlairInfo);
            niftiwrite(app.Lesion2Draft, fullfile(folder, 'ses-02_lesion_draft'),app.T2FlairInfo);

        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            view(app.LesionVisualizationAxes, [0 1 0]); % Set view to sagittal
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            view(app.LesionVisualizationAxes, [1 0 0]); % Set view to coronal
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            view(app.LesionVisualizationAxes, [0 0 1]); % Set view to Ax
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            if strcmp(app.rotationTimer.Running, 'off')
                start(app.rotationTimer);  % Start the timer, and hence the rotation, if it's not already running
            else
                stop(app.rotationTimer);   % Stop the timer, and hence the rotation, if it's currently running
            end
        end

        % Button pushed function: savegifButton
        function savegifButtonPushed(app, event)
             saveUIAxesAsGif(app);
        end

        % Value changed function: newCheckBox
        function newCheckBoxValueChanged(app, event)
            if app.newCheckBox.Value
                % Checkbox is checked. Limit spinner to new lesions.
                if isempty(app.newLesionIndex)
                    % Handle case where there are no new lesions.
                    app.LesionIndexSpinner.Limits = [1, 1];
                    app.LesionIndexSpinner.Value = 1;
                    app.LesionIndexSpinner.Enable = 'off';
                else
                    app.LesionIndexSpinner.Limits = [1, length(app.newLesionIndex)];
                    app.LesionIndexSpinner.Value = 1; % Reset to first new lesion
                    app.LesionIndexSpinner.Enable = 'on';
                end
            else
                % Checkbox is unchecked. Allow all lesions.
                app.LesionIndexSpinner.Limits = [1, 25]; % Assuming 25 is the max number of lesions
                app.LesionIndexSpinner.Enable = 'on';
            end

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % folder = uigetdir; % Let user select save directory
            % if folder == 0
            %     return; % User cancelled
            % end
            % 
            % Define A4 size in points
            %% Initialize the PDF filename
            whichPage = 1;

           % pdfFilename1 = fullfile(folder, sprintf('%s_lesionReport_%s.pdf', app.subject, num2str(whichPage)));
            pdfFilename1 = fullfile(app.bidsDir,'derivatives','Report',app.subject, sprintf('%s_lesionReport_%s.pdf', app.subject, num2str(whichPage)));
            mkdir(fullfile(app.bidsDir,'derivatives','Report',app.subject))
            %% Create figure sized as A4 in portrait orientation
            % Define A4 size in pixels at 300 DPI (dots per inch)


            setupFigure(app);

            %% Add title text at the top of the page
            % Use normalized units for positioning
            annotation(app.fig,'rectangle',[0, 0.95, 1, 0.1],'FaceColor',[0.5 0.5 0.5],'FaceAlpha',.2,'EdgeColor', 'none');

            annotation(app.fig, 'textbox', [0, 0.925, 1, 0.1], 'String', ['Lesion report of ' app.subject], ...
                'FontSize', 28, 'FontWeight','bold','VerticalAlignment', 'middle','HorizontalAlignment', 'center','EdgeColor', 'none', 'Interpreter', 'none');

            %% Process and display each image
            for k = 1:2 %  loop through two images
                updateProgress(app,['Saving to report: 3D view timepoint ' num2str(k) ' ...'])
                app.currentImageIndex = 3 - k;
                app.LesionVisibilitySwitch.Value = 'All';
                toggleT2Image(app); % update the visualization
                view(app.LesionVisualizationAxes, [0 0 1]); % Set view to Axial

                % update lesion color
                for ii = 1:numel(app.lesionPatches)
                    patchInfo = app.lesionPatches{ii};
                    lesionPatch = patchInfo{1}; % The patch object
                    if sum(double(patchInfo{3} == app.newLesionIndex))>0                         
                        if patchInfo{2} == 2
                            lesionPatch.FaceColor = [1 0 0]; % new lesion red
                        else
                            lesionPatch.FaceColor = [0 1 0]; % old lesion green
                        end
                    else
                        lesionPatch.FaceColor = [0 1 0]; % old lesion green
                    end
                end
                        
                % Temporarily export each frame to a PNG file for high-resolution capture
                tempFilename1 = sprintf('tempFrame1_%d.png', k);
                exportgraphics(app.LesionVisualizationAxes, tempFilename1, 'Resolution', 300);

                % Read the captured image
                [img, ~] = imread(tempFilename1);
                img = rot90(img); % Rotate 90 degrees counterclockwise

                % Calculate target position for the image
                if k == 1
                    % First image positioning
                    targetPosition = [0.0640    0.66    0.2019    size(img,1)/size(img,2)*0.2019]; % Left bottom width height

                else
                    % Second image positioning
                    targetPosition = [0.3    0.66    0.2019    size(img,1)/size(img,2)*0.2019]; % 0.1711
                end

                % Create axes for the image and adjust to target position
                imgAxes = axes('Parent', app.fig, 'Position', targetPosition);
                imshow(img, 'Parent', imgAxes);

                if k == 1 % if time point 1
                    text(0.525, 1, 'baseline', ...
                        'FontSize', 15, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                        'Units', 'normalized', 'Parent', imgAxes,'FontWeight','bold');

                else
                    text(0.525, 1, 'followup', ...
                        'FontSize', 15, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                        'Units', 'normalized', 'Parent', imgAxes,'FontWeight','bold');

                end

                set(imgAxes, 'XTick', [], 'YTick', [], 'Box', 'on');


                % Clean up temporary file
                delete(tempFilename1);
            end

            %% Prepare the info text
            infoTextLines = {
                sprintf('Baseline - %d lesions (%d ml)', nnz(unique(app.L1)), round(nnz(app.L1(:)) * app.voxelSize)),
                sprintf('Followup - %d lesions (%d ml)', nnz(unique(app.L2)), round(nnz(app.L2(:)) * app.voxelSize))
                };

            % Define the starting position for the text (bottom-right corner in normalized units)
            infoPositionX = 0.55; % Horizontal start position in normalized units
            infoPositionYStart = 0.85; % Vertical start position in normalized units
            lineSpacing = 0.05; % Space between lines in normalized units

            % Create a full-size invisible axes for positioning text
            infoAxes = axes('Parent', app.fig, 'Position', [0, 0, 1, 1], 'Visible', 'off');
            set(infoAxes, 'XLim', [0, 1], 'YLim', [0, 1], 'YDir', 'reverse');

            % Loop through the lines of text and place them
            for ii = 1:length(infoTextLines)
                text(infoPositionX, infoPositionYStart - (ii-1)*lineSpacing, infoTextLines{ii}, ...
                    'FontSize', 15, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'top', ...
                    'Units', 'normalized', 'Parent', infoAxes,'FontWeight','bold');
            end

            %% bar graph of lesion size change

            barPlotAxes = axes('Parent', app.fig, 'Position', [0.15, 0.5, 0.7, 0.15]);
            hold(barPlotAxes, 'on');

            % Define bar width
            barWidth = 0.4;

            % Create x values for baseline and follow-up groups
            xBase = 1:size(app.sizeChange, 1); % Baseline lesions
            xFollow = xBase + barWidth; % Follow-up lesions, plotted next to baseline

            % Plot baseline bars in gray
            bar(barPlotAxes, xBase, app.sizeChange(:, 1), barWidth, 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'none');

            % Plot follow-up bars, color by size change
            for ii = 1:length(xBase)
                if app.sizeChange(ii, 3) > 0
                    % Lesion size increased - color red
                    followColor = [237 106 94]./255;
                else
                    % Lesion size decreased or unchanged - color blue
                    followColor = [83 131 236]./255;
                end
                bar(barPlotAxes, xFollow(ii), app.sizeChange(ii, 2), barWidth, 'FaceColor', followColor, 'EdgeColor', 'none');
            end

            % Customize the plot appearance
            title(barPlotAxes, 'Size change between baseline and followup');
            ylabel(barPlotAxes, 'Volume (ml)');
            xlabel(barPlotAxes, 'Individual lesions sorted by size');
            set(barPlotAxes, 'LineWidth', 2, 'FontSize', 15, 'FontWeight', 'bold', 'FontAngle', 'italic', 'TickDir', 'out', 'XTick', [], 'XTickLabel', []);

            %% Draw the largest lesion on bottom of page 1

            whereReport = 2;
            app.currentIndex = app.lesionIndex(1);
            drawReport(app,whereReport);

            updateProgress(app,['Exporting ' pdfFilename1 ' ...'])
            print(app.fig, pdfFilename1, '-dpdf', '-r300');
            close(app.fig);
            updateProgress(app,'Page 1 done!')

            %% fill the 2nd+ pages with new lesions
            % This is budget workaround, not the best practice, please find
            % another way
            
            % Export only the new lesions that have passed the review
            app.newLesionIndexExport = [];

            for ii = 1:numel(app.newLesionIndex)
                if strcmp(app.LesionReviewStates(app.newLesionIndex(ii),2), 'keep')
                    app.newLesionIndexExport = [app.newLesionIndexExport; app.newLesionIndex(ii)];
                end

            end

            if isempty(app.newLesionIndexExport)

                updateProgress(app,['No new lesions found ...'])
                morePages = 0;

            else

                morePages = ceil(numel(app.newLesionIndexExport)/2);
                whichLesion = 1; % we always draw 2 lesions per page, and always draw first new lesion on page 2


                for whichPage = 2:(1+morePages)

                    % check if it's half a page or one full page
                    numLes = 2;
                    if whichPage == (1+morePages)
                        numLes = double(rem(numel(app.newLesionIndexExport)/2,1)==0)+1;
                    end
         
                  % pdfFilename1 = fullfile(folder, sprintf('%s_lesionReport_%s.pdf', app.subject, num2str(whichPage)));
                    pdfFilename1 = fullfile(app.bidsDir,'derivatives','Report',app.subject, sprintf('%s_lesionReport_%s.pdf', app.subject, num2str(whichPage)));

                    setupFigure(app);

                    % Add title text at the top of the page
                    annotation(app.fig,'rectangle',[0, 0.95, 1, 0.1],'FaceColor',[0.5 0.5 0.5],'FaceAlpha',.2,'EdgeColor', 'none');

                    annotation(app.fig, 'textbox', [0, 0.925, 1, 0.1], 'String', ['Possible New Lesions'], ...
                        'FontSize', 28, 'FontWeight','bold','VerticalAlignment', 'middle','HorizontalAlignment', 'left','EdgeColor', 'none', 'Interpreter', 'none');


                    for ii = 1:numLes % loop through the lesions to draw on the current page
                        app.currentIndex = app.newLesionIndexExport(whichLesion);

                        if ~rem(whichLesion,2) % even index, draw at bottom of page
                            drawReport(app,2);
                        else % odd index, draw at top of page

                            drawReport(app,1);
                        end
                        whichLesion = whichLesion + 1; % goes to next lesion
                    end

                    updateProgress(app,['Exporting ' pdfFilename1 ' ...'])
                    print(app.fig, pdfFilename1, '-dpdf', '-r300');
                    close(app.fig);
                    updateProgress(app,['Page ' num2str(whichPage) ' done!'])

                end

            end


            % inputFiles = cell(1, 1+morePages);
            % for ii = 1:(1+morePages)
            %     inputFiles{ii} = fullfile(folder, sprintf('%s_lesionReport_%d.pdf', app.subject, ii));
            % end
            % outputFile = fullfile(folder, sprintf('%s_lesionReport.pdf', app.subject));
            inputFiles = cell(1, 1+morePages);
            for ii = 1:(1+morePages)
                inputFiles{ii} = fullfile(app.bidsDir,'derivatives','Report',app.subject, sprintf('%s_lesionReport_%d.pdf', app.subject, ii));
            end
            outputFile = fullfile(app.bidsDir,'derivatives','Report',app.subject, sprintf('%s_lesionReport.pdf', app.subject));


            % Create the Ghostscript command for merging
            %cmd = sprintf('/opt/homebrew/bin/gs -dBATCH -dNOPAUSE -q -sDEVICE=pdfwrite -sOutputFile="%s"', outputFile);
            
            cmd = sprintf('%s/gs -dBATCH -dNOPAUSE -q -sDEVICE=pdfwrite -sOutputFile="%s"',pwd,outputFile);

            % Append each input file to the command
            for ii = 1:length(inputFiles)
                cmd = sprintf('%s "%s"', cmd, inputFiles{ii});
            end

            % Execute the command
            [status, cmdout] = system(cmd);

            if status == 0
                % Delete individual PDF files
                for ii = 1:length(inputFiles)
                    delete(inputFiles{ii});
                end
                updateProgress(app,'PDF files were merged successfully.');
            else
                updateProgress(app,['An error occurred: ', cmdout]);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LesionVisualizationAxes = uiaxes(app.UIFigure);
            ylabel(app.LesionVisualizationAxes, 'Y')
            app.LesionVisualizationAxes.XTick = [];
            app.LesionVisualizationAxes.YTick = [];
            app.LesionVisualizationAxes.ZTick = [];
            app.Panel_3 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_3);
            app.ButtonGroup = uibuttongroup(app.Panel_3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '2';
            app.PlayButton = uitogglebutton(app.ButtonGroup);
            app.PlayButton.Text = 'Play';
            app.y0 = uispinner(app.ButtonGroup);
            app.y0.ValueChangedFcn = createCallbackFcn(app, @y0ValueChanged, true);
            app.z0 = uispinner(app.ButtonGroup);
            app.z0.ValueChangedFcn = createCallbackFcn(app, @z0ValueChanged, true);
            app.x0 = uispinner(app.ButtonGroup);
            app.x0.ValueChangedFcn = createCallbackFcn(app, @x0ValueChanged, true);
            app.ExportButton = uibutton(app.ButtonGroup, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.OverlayAlpha = uislider(app.Panel_3);
            app.OverlayAlpha.Limits = [0 1];
            app.OverlayAlpha.MajorTicks = [];
            app.OverlayAlpha.MajorTickLabels = {''};
            app.OverlayAlpha.ValueChangedFcn = createCallbackFcn(app, @OverlayAlphaValueChanged, true);
            app.OverlayAlpha.ValueChangingFcn = createCallbackFcn(app, @OverlayAlphaValueChanging, true);
            app.OverlayAlpha.MinorTicks = [];
            app.OverlayAlpha.Value = 0.5;
            app.Loadlesion1Button = uibutton(app.Panel_3, 'push');
            app.Loadlesion1Button.ButtonPushedFcn = createCallbackFcn(app, @Loadlesion1ButtonPushed, true);
            app.Loadlesion1Button.Text = 'Load lesion 1';
            app.Loadlesion2Button = uibutton(app.Panel_3, 'push');
            app.Loadlesion2Button.ButtonPushedFcn = createCallbackFcn(app, @Loadlesion2ButtonPushed, true);
            app.Loadlesion2Button.Text = 'Load lesion 2';
            app.LoadBrainMaskButton = uibutton(app.Panel_3, 'push');
            app.LoadBrainMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBrainMaskButtonPushed, true);
            app.LoadBrainMaskButton.Text = 'Mask';
            app.BIDSButton = uibutton(app.Panel_3, 'push');
            app.BIDSButton.ButtonPushedFcn = createCallbackFcn(app, @BIDSButtonPushed, true);
            app.BIDSButton.Text = 'BIDS';
            app.SAVEniiButton = uibutton(app.Panel_3, 'push');
            app.SAVEniiButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEniiButtonPushed, true);
            app.SAVEniiButton.Text = {'SAVE'; '.nii'};
            app.LoadT2FlairTP1Button = uibutton(app.Panel_3, 'push');
            app.LoadT2FlairTP1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadT2FlairTP1ButtonPushed, true);
            app.LoadT2FlairTP1Button.Text = 'Load T2 Flair Time Point 1';
            app.LoadT2FlairTP2Button = uibutton(app.Panel_3, 'push');
            app.LoadT2FlairTP2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadT2FlairTP2ButtonPushed, true);
            app.LoadT2FlairTP2Button.Text = 'Load T2 Flair Time Point 2';
            app.savegifButton = uibutton(app.Panel_3, 'push');
            app.savegifButton.ButtonPushedFcn = createCallbackFcn(app, @savegifButtonPushed, true);
            app.savegifButton.Text = {'save '; '.gif'};
            app.ProgressTextArea = uitextarea(app.Panel_3);
            app.ProgressTextArea.Editable = 'off';
            app.LesionIndexSpinner = uispinner(app.UIFigure);
            app.LesionIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @LesionIndexSpinnerValueChanged, true);
            app.LesionVisibilitySwitch = uiswitch(app.UIFigure, 'slider');
            app.LesionVisibilitySwitch.Items = {'One', 'All'};
            app.LesionVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @LesionVisibilitySwitchValueChanged, true);
            app.LesionVisibilitySwitch.Value = 'One';
            app.LesionReviewButtonGroup = uibuttongroup(app.UIFigure);
            app.LesionReviewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LesionReviewButtonGroupSelectionChanged, true);
            app.KeepButton = uitogglebutton(app.LesionReviewButtonGroup);
            app.KeepButton.Text = 'Keep';
            app.KeepButton.Value = true;
            app.DraftButton = uitogglebutton(app.LesionReviewButtonGroup);
            app.DraftButton.Text = 'Draft';
            app.DeleteButton = uitogglebutton(app.LesionReviewButtonGroup);
            app.DeleteButton.Text = 'Delete';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.newCheckBox = uicheckbox(app.UIFigure);
            app.newCheckBox.ValueChangedFcn = createCallbackFcn(app, @newCheckBoxValueChanged, true);
            app.newCheckBox.Text = 'new';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lesionView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end